classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        DataDirectoryEditFieldLabel     matlab.ui.control.Label
        DataDirectoryEditField          matlab.ui.control.EditField
        Button                          matlab.ui.control.Button
        DetectionDropDownLabel          matlab.ui.control.Label
        DetectionDropDown               matlab.ui.control.DropDown
        Button_2                        matlab.ui.control.Button
        Button_3                        matlab.ui.control.Button
        ExtractionDropDownLabel         matlab.ui.control.Label
        ExtractionDropDown              matlab.ui.control.DropDown
        LoadBoFButton                   matlab.ui.control.Button
        Button_4                        matlab.ui.control.Button
        OutputDirectoryEditFieldLabel   matlab.ui.control.Label
        OutputDirectoryEditField        matlab.ui.control.EditField
        RunClassifierButton             matlab.ui.control.Button
        ExportBoFCheckBox               matlab.ui.control.CheckBox
        ExportClassifierCheckBox        matlab.ui.control.CheckBox
        ImageResolutionEditFieldLabel   matlab.ui.control.Label
        ySize                           matlab.ui.control.NumericEditField
        xEditFieldLabel                 matlab.ui.control.Label
        xSize                           matlab.ui.control.NumericEditField
        UnloadBoFButton                 matlab.ui.control.Button
        LoadClassifButton               matlab.ui.control.Button
        UnloadClassifButton             matlab.ui.control.Button
        LoadSettingsButton              matlab.ui.control.Button
        CenterPanel                     matlab.ui.container.Panel
        UIAxes                          matlab.ui.control.UIAxes
        PopOutButton                    matlab.ui.control.Button
        SelectSetDropDownLabel          matlab.ui.control.Label
        SelectSetDropDown               matlab.ui.control.DropDown
        Index1Label                     matlab.ui.control.Label
        Index1                          matlab.ui.control.NumericEditField
        Index2Label                     matlab.ui.control.Label
        Index2                          matlab.ui.control.NumericEditField
        UpdateButton                    matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        InfoLabel                       matlab.ui.control.Label
        ExtractionLabel                 matlab.ui.control.Label
        DetectionLabel                  matlab.ui.control.Label
        CurrentBoFLabel                 matlab.ui.control.Label
        ImageLabelTable                 matlab.ui.control.Table
        ResultAccuracyLabel             matlab.ui.control.Label
        Button_5                        matlab.ui.control.Button
        SelectindividualImageEditFieldLabel  matlab.ui.control.Label
        SelectindividualImageEditField  matlab.ui.control.EditField
        PredictButton                   matlab.ui.control.Button
        CurrentClassifierLabel          matlab.ui.control.Label
        PredictedLabel                  matlab.ui.control.Label
        LoadedSettingsLabel             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        storage;            % Stores all app state
        testImagePath;      % Path to test image 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.storage = app_storage.instance();
            app.ySize.Value = 0;
            app.xSize.Value = 0;
            
            app.storage.BOFisset = 0;
            app.storage.CLASSisset = 0;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            switch app.DetectionDropDown.Value
                case "FAST"
                    d_fast(app);
                case "Harris"
                    d_harris(app);
                case "SURF"
                    d_surf(app);
                case "KAZE"
                    d_kaze(app);
                case "BRISK"
                    d_brisk(app);
                case "ORB"
                    d_orb(app);
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            switch app.ExtractionDropDown.Value
                case "HOG"
                    e_hog(app);
                case "SURF"
                    e_surf(app);
                case "KAZE"
                    e_kaze(app);
            end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.storage.dataDirectory = uigetdir("E:\dev");
            app.DataDirectoryEditField.Value = app.storage.dataDirectory;
            app.UIFigure.Visible = 'on';
            
            app.storage.imageData = imageDatastore( ...
                app.storage.dataDirectory, "IncludeSubfolders", true, "LabelSource", "foldernames");
            
            app.SelectSetDropDown.Items = unique(app.storage.imageData.Labels);
            
            labels = countEachLabel(app.storage.imageData);
            app.ImageLabelTable.Data = labels;
            
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            % update detection, extraction, image resolution
            app.storage.detectionMethod = app.DetectionDropDown.Value;
            app.storage.extractionMethod = app.ExtractionDropDown.Value;
      
            imds = app.storage.imageData;
            
            index1 = (find(app.storage.imageData.Labels==app.SelectSetDropDown.Value, 1) - 1) + app.Index1.Value;
            index2 = index1 + app.Index2.Value;
            
            if app.ySize.Value == 0 && app.xSize.Value == 0
                firstImage = rgb2gray(readimage(imds, index1));
                secondImage = rgb2gray(readimage(imds, index2));
            else
                firstImage = imresize(rgb2gray(readimage(imds, index1)), [app.ySize.Value, app.xSize.Value]);
                secondImage = imresize(rgb2gray(readimage(imds, index2)), [app.ySize.Value, app.xSize.Value]);
            end
            
            % TODO: add the parameters to preview
            firstPoints = detect_points(firstImage, app.storage);
            secondPoints = detect_points(secondImage, app.storage);
            
            [firstFeatures, firstVP] = extract_features(firstImage, firstPoints, app.storage);
            [secondFeatures, secondVP] = extract_features(secondImage, secondPoints, app.storage);
            
            matches = matchFeatures(firstFeatures, secondFeatures);

            matchedFrist = firstVP(matches(:, 1));
            matchedSecond = secondVP(matches(:, 2));
            
            % TODO: look at how to make this one work
            % [~, inlierSecond, inlierFirst] = ...
            %     estimateGeometricTransform(matchedSecond, matchedFrist, 'similarity');
            
            showMatchedFeatures(firstImage, secondImage, matchedFrist, matchedSecond, "montage", "Parent", app.UIAxes);
        end

        % Button pushed function: RunClassifierButton
        function RunClassifierButtonPushed(app, event)
            diary output.txt
            
            wb = waitbar(0, "Starting");
            app.DetectionLabel.Text = strcat("Detection: ", app.storage.detectionMethod);
            app.ExtractionLabel.Text = strcat("Extraction: ", app.storage.extractionMethod); 
            
            try 
                bag_of_features(app.storage.imageData, app.storage, wb);
                app.CurrentBoFLabel.Text = strcat("Current BoF: ", "Last Run");
                app.CurrentClassifierLabel.Text = strcat("Current Classifier: ", "Last Run");
                app.ResultAccuracyLabel.Text = strcat("Result Accuracy: ", num2str(app.storage.resultAccuracy));    
                
                app.storage.BOFisset = 1;
                app.storage.CLASSisset = 1;
            catch ME
                msgbox(ME.message, ME.identifier, 'error');
            end 
            
            % result_mat(trainMat, testMat);
            
            close(wb);
            
            diary off
        end

        % Value changed function: ExportBoFCheckBox
        function ExportBoFCheckBoxValueChanged(app, event)
            app.storage.shouldExportBOF = app.ExportBoFCheckBox.Value;
            disp(app.storage.shouldExportBOF);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            app.storage.outputDirectory = uigetdir("E:\dev\bachelor\matlab\_output");
            app.OutputDirectoryEditField.Value = app.storage.outputDirectory;
            
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            [file, path] = uigetfile('*');
            app.testImagePath = strcat(path, file);
            app.SelectindividualImageEditField.Value = app.testImagePath;
            
            app.UIFigure.Visible = 'on';
        end

        % Button pushed function: PredictButton
        function PredictButtonPushed(app, event)
            if app.storage.CLASSisset == 1
                image = imread(app.testImagePath);
                
                % TODO: figure out how the score works
                [labelIdx, ~] = predict(app.storage.selectedClassifier, image);
                
                label = app.storage.selectedClassifier.Labels(labelIdx);
                app.PredictedLabel.Text = strcat("Predicted Label: " + label);
            else 
                msgbox("Load a classifier before testing images", "No Classifier specified", 'error');
            end
        end

        % Button pushed function: LoadBoFButton
        function LoadBoFButtonPushed(app, event)
            [file, path] = uigetfile('.mat');
            temp = load(strcat(path, file));
            app.storage.selectedBOF = temp.bag; 
            
            app.CurrentBoFLabel.Text = strcat("Current BoF: ", file);
            
            app.storage.BOFisset = 1;          
        end

        % Button pushed function: UnloadBoFButton
        function UnloadBoFButtonPushed(app, event)
            app.storage.BOFisset = 0;
            app.CurrentBoFLabel.Text = "Current BoF: ";
        end

        % Value changed function: DetectionDropDown
        function DetectionDropDownValueChanged(app, event)
            app.storage.detectionMethod = app.DetectionDropDown.Value;
        end

        % Value changed function: ExtractionDropDown
        function ExtractionDropDownValueChanged(app, event)
            app.storage.extractionMethod = app.ExtractionDropDown.Value;
        end

        % Value changed function: ExportClassifierCheckBox
        function ExportClassifierCheckBoxValueChanged(app, event)
            app.storage.shouldExportClassifier = app.ExportClassifierCheckBox.Value;
        end

        % Button pushed function: LoadClassifButton
        function LoadClassifButtonPushed(app, event)
            [file, path] = uigetfile('.mat');
            temp = load(strcat(path, file));
            app.storage.selectedClassifier = temp.classifier; 
            
            app.CurrentClassifierLabel.Text = strcat("Current Classifier: ", file);
            
            app.ImageLabelTable.Data = transpose(app.storage.selectedClassifier.Labels);
            
            app.storage.CLASSisset = 1;  
        end

        % Button pushed function: UnloadClassifButton
        function UnloadClassifButtonPushed(app, event)
            app.storage.CLASSisset = 0;
            app.CurrentClassifierLabel.Text = "Current Classifier:";
        end

        % Button pushed function: LoadSettingsButton
        function LoadSettingsButtonPushed(app, event)
            [file, path] = uigetfile('.txt');
            settings_io.readSettingsFromFile(strcat(path, file));
            
            len = strlength(path);
            
            app.LoadedSettingsLabel.Text = strcat("Loaded Settings: ", extractBetween(path, len-7, len));
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {560, 560, 560};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {560, 560};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataDirectoryEditFieldLabel = uilabel(app.LeftPanel);
            app.DataDirectoryEditFieldLabel.Text = 'Data Directory';
            app.DataDirectoryEditField = uieditfield(app.LeftPanel, 'text');
            app.DataDirectoryEditField.Value = 'c:\';
            app.Button = uibutton(app.LeftPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '...';
            app.DetectionDropDownLabel = uilabel(app.LeftPanel);
            app.DetectionDropDownLabel.Text = 'Detection';
            app.DetectionDropDown = uidropdown(app.LeftPanel);
            app.DetectionDropDown.Items = {'FAST', 'Harris', 'SURF', 'KAZE', 'BRISK', 'ORB'};
            app.DetectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionDropDownValueChanged, true);
            app.DetectionDropDown.Value = 'FAST';
            app.Button_2 = uibutton(app.LeftPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '...';
            app.Button_3 = uibutton(app.LeftPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '...';
            app.ExtractionDropDownLabel = uilabel(app.LeftPanel);
            app.ExtractionDropDownLabel.Text = 'Extraction';
            app.ExtractionDropDown = uidropdown(app.LeftPanel);
            app.ExtractionDropDown.Items = {'HOG', 'SURF', 'KAZE', 'FREAK', 'BRISK', 'ORB'};
            app.ExtractionDropDown.ValueChangedFcn = createCallbackFcn(app, @ExtractionDropDownValueChanged, true);
            app.ExtractionDropDown.Value = 'HOG';
            app.LoadBoFButton = uibutton(app.LeftPanel, 'push');
            app.LoadBoFButton.ButtonPushedFcn = createCallbackFcn(app, @LoadBoFButtonPushed, true);
            app.LoadBoFButton.Text = 'Load BoF';
            app.Button_4 = uibutton(app.LeftPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '...';
            app.OutputDirectoryEditFieldLabel = uilabel(app.LeftPanel);
            app.OutputDirectoryEditFieldLabel.Text = 'Output Directory';
            app.OutputDirectoryEditField = uieditfield(app.LeftPanel, 'text');
            app.OutputDirectoryEditField.Value = 'c:\';
            app.RunClassifierButton = uibutton(app.LeftPanel, 'push');
            app.RunClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @RunClassifierButtonPushed, true);
            app.RunClassifierButton.Text = 'Run Classifier';
            app.ExportBoFCheckBox = uicheckbox(app.LeftPanel);
            app.ExportBoFCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportBoFCheckBoxValueChanged, true);
            app.ExportBoFCheckBox.Text = 'Export BoF';
            app.ExportClassifierCheckBox = uicheckbox(app.LeftPanel);
            app.ExportClassifierCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportClassifierCheckBoxValueChanged, true);
            app.ExportClassifierCheckBox.Text = 'Export Classifier';
            app.ImageResolutionEditFieldLabel = uilabel(app.LeftPanel);
            app.ImageResolutionEditFieldLabel.Text = 'Image Resolution';
            app.ySize = uieditfield(app.LeftPanel, 'numeric');
            app.ySize.Limits = [0 Inf];
            app.ySize.ValueDisplayFormat = '%.0f';
            app.xEditFieldLabel = uilabel(app.LeftPanel);
            app.xEditFieldLabel.Text = 'x';
            app.xSize = uieditfield(app.LeftPanel, 'numeric');
            app.xSize.Limits = [0 Inf];
            app.xSize.ValueDisplayFormat = '%.0f';
            app.UnloadBoFButton = uibutton(app.LeftPanel, 'push');
            app.UnloadBoFButton.ButtonPushedFcn = createCallbackFcn(app, @UnloadBoFButtonPushed, true);
            app.UnloadBoFButton.Text = 'Unload BoF';
            app.LoadClassifButton = uibutton(app.LeftPanel, 'push');
            app.LoadClassifButton.ButtonPushedFcn = createCallbackFcn(app, @LoadClassifButtonPushed, true);
            app.LoadClassifButton.Text = 'Load Classif.';
            app.UnloadClassifButton = uibutton(app.LeftPanel, 'push');
            app.UnloadClassifButton.ButtonPushedFcn = createCallbackFcn(app, @UnloadClassifButtonPushed, true);
            app.UnloadClassifButton.Text = 'Unload Classif.';
            app.LoadSettingsButton = uibutton(app.LeftPanel, 'push');
            app.LoadSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSettingsButtonPushed, true);
            app.LoadSettingsButton.Text = 'Load Settings';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PopOutButton = uibutton(app.CenterPanel, 'push');
            app.PopOutButton.Text = 'Pop Out';
            app.SelectSetDropDownLabel = uilabel(app.CenterPanel);
            app.SelectSetDropDownLabel.Text = 'Select Set';
            app.SelectSetDropDown = uidropdown(app.CenterPanel);
            app.SelectSetDropDown.Items = {};
            app.SelectSetDropDown.Value = {};
            app.Index1Label = uilabel(app.CenterPanel);
            app.Index1Label.Text = 'Index 1';
            app.Index1 = uieditfield(app.CenterPanel, 'numeric');
            app.Index1.Limits = [0 Inf];
            app.Index1.ValueDisplayFormat = '%.0f';
            app.Index1.Value = 1;
            app.Index2Label = uilabel(app.CenterPanel);
            app.Index2Label.Text = 'Index 2';
            app.Index2 = uieditfield(app.CenterPanel, 'numeric');
            app.Index2.Limits = [0 Inf];
            app.Index2.ValueDisplayFormat = '%.0f';
            app.Index2.Value = 2;
            app.UpdateButton = uibutton(app.CenterPanel, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.RightPanel = uipanel(app.GridLayout);
            app.InfoLabel = uilabel(app.RightPanel);
            app.InfoLabel.Text = 'Info';
            app.ExtractionLabel = uilabel(app.RightPanel);
            app.ExtractionLabel.Text = 'Extraction:';
            app.DetectionLabel = uilabel(app.RightPanel);
            app.DetectionLabel.Text = 'Detection:';
            app.CurrentBoFLabel = uilabel(app.RightPanel);
            app.CurrentBoFLabel.Text = 'Current BoF:';
            app.ImageLabelTable = uitable(app.RightPanel);
            app.ImageLabelTable.ColumnName = {'Label'; 'Count'};
            app.ImageLabelTable.RowName = {};
            app.ResultAccuracyLabel = uilabel(app.RightPanel);
            app.ResultAccuracyLabel.Text = 'Result Accuracy: ';
            app.Button_5 = uibutton(app.RightPanel, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '...';
            app.SelectindividualImageEditFieldLabel = uilabel(app.RightPanel);
            app.SelectindividualImageEditFieldLabel.Text = 'Select individual Image';
            app.SelectindividualImageEditField = uieditfield(app.RightPanel, 'text');
            app.SelectindividualImageEditField.Value = 'c:\';
            app.PredictButton = uibutton(app.RightPanel, 'push');
            app.PredictButton.ButtonPushedFcn = createCallbackFcn(app, @PredictButtonPushed, true);
            app.PredictButton.Text = 'Predict';
            app.CurrentClassifierLabel = uilabel(app.RightPanel);
            app.CurrentClassifierLabel.Text = 'Current Classifier:';
            app.PredictedLabel = uilabel(app.RightPanel);
            app.PredictedLabel.Text = 'Predicted Label: ';
            app.LoadedSettingsLabel = uilabel(app.RightPanel);
            app.LoadedSettingsLabel.Text = 'Loaded Settings:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end