classdef cow_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InputPanel               matlab.ui.container.Panel
        DataDirEditFieldLabel    matlab.ui.control.Label
        DataDirEditField         matlab.ui.control.EditField
        SearchButton             matlab.ui.control.Button
        DetectionDropDownLabel   matlab.ui.control.Label
        DetectionDropDown        matlab.ui.control.DropDown
        SearchButton_2           matlab.ui.control.Button
        OutputDirEditFieldLabel  matlab.ui.control.Label
        OutputDirEditField       matlab.ui.control.EditField
        LoadDataButton           matlab.ui.control.Button
        RunClassifierButton      matlab.ui.control.Button
        ExtractionDropDownLabel  matlab.ui.control.Label
        ExtractionDropDown       matlab.ui.control.DropDown
        SearchButton_3           matlab.ui.control.Button
        UseBoFEditFieldLabel     matlab.ui.control.Label
        UseBoFEditField          matlab.ui.control.EditField
        ClassifierPreviewPanel   matlab.ui.container.Panel
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        datadir     % Directory that contains the dataset
        outputdir   % Directory that is used to save results
        
        storage    % Stores the detection and extraction method
        imds        % Image datastore for current directory
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            app.datadir = uigetdir("C:\");
            app.DataDirEditField.Value = app.datadir;
        end

        % Button pushed function: SearchButton_2
        function SearchButton_2Pushed(app, event)
            app.outputdir = uigetdir("C:\");
            app.OutputDirEditField.value = app.outputdir;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            app.imds = imageDatastore(...
                app.datadir, "IncludeSubfolders", true, "LabelSource", "foldernames");
            
            firstImage = rgb2gray(readimage(app.imds, 1));
            secondImage = rgb2gray(readimage(app.imds, 2));
            
            firstPoints = detect_points(firstImage, app.DetectionDropDown.Value);
            secondPoints = detect_points(secondImage, app.DetectionDropDown.Value);
            
            [firstFeatures, firstVP] = extract_features(firstImage, firstPoints, app.ExtractionDropDown.Value);
            [secondFeatures, secondVP] = extract_features(secondImage, secondPoints, app.ExtractionDropDown.Value);
            
            app.storage = app_storage.instance();
            app.storage.detectionMethod = app.DetectionDropDown.Value;
            app.storage.extractionMethod = app.ExtractionDropDown.Value;
            
            matches = matchFeatures(firstFeatures, secondFeatures);

            matchedFrist = firstVP(matches(:, 1));
            matchedSecond = secondVP(matches(:, 2));
            
            [~, inlierSecond, inlierFirst] = ...
                estimateGeometricTransform(matchedSecond, matchedFrist, 'similarity');
            
            showMatchedFeatures(firstImage, secondImage, inlierFirst, inlierSecond, "montage", "Parent", app.UIAxes);
            % legend(ax, 'ptsFrist', 'pointsSecond');
        end

        % Button pushed function: RunClassifierButton
        function RunClassifierButtonPushed(app, event)
            diary output.txt
            
            disp(app.storage);
            
            bag_of_features(app.imds);
            
            diary off
            % save("test.txt", "output", "-ascii");
        end

        % Button pushed function: SearchButton_3
        function SearchButton_3Pushed(app, event)
            app.outputdir = uigetdir("C:\");
            app.OutputDirEditField.value = app.outputdir;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputPanel = uipanel(app.UIFigure);
            app.InputPanel.Title = 'Input';
            app.DataDirEditFieldLabel = uilabel(app.InputPanel);
            app.DataDirEditFieldLabel.Text = 'Data Dir';
            app.DataDirEditField = uieditfield(app.InputPanel, 'text');
            app.DataDirEditField.Value = 'c:\';
            app.SearchButton = uibutton(app.InputPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.DetectionDropDownLabel = uilabel(app.InputPanel);
            app.DetectionDropDownLabel.Text = 'Detection';
            app.DetectionDropDown = uidropdown(app.InputPanel);
            app.DetectionDropDown.Items = {'SURF', 'FAST', 'KAZE', 'MinEigValue', 'BRISK', 'Harris', 'MSER', 'ORB'};
            app.DetectionDropDown.Value = 'SURF';
            app.SearchButton_2 = uibutton(app.InputPanel, 'push');
            app.SearchButton_2.ButtonPushedFcn = createCallbackFcn(app, @SearchButton_2Pushed, true);
            app.SearchButton_2.Text = 'Search';
            app.OutputDirEditFieldLabel = uilabel(app.InputPanel);
            app.OutputDirEditFieldLabel.Text = 'Output Dir';
            app.OutputDirEditField = uieditfield(app.InputPanel, 'text');
            app.OutputDirEditField.Value = 'c:\';
            app.LoadDataButton = uibutton(app.InputPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.RunClassifierButton = uibutton(app.InputPanel, 'push');
            app.RunClassifierButton.ButtonPushedFcn = createCallbackFcn(app, @RunClassifierButtonPushed, true);
            app.RunClassifierButton.Text = 'Run Classifier';
            app.ExtractionDropDownLabel = uilabel(app.InputPanel);
            app.ExtractionDropDownLabel.Text = 'Extraction';
            app.ExtractionDropDown = uidropdown(app.InputPanel);
            app.ExtractionDropDown.Items = {'SURF', 'KAZE', 'FREAK', 'BRISK', 'ORB', 'Block', 'HOG'};
            app.ExtractionDropDown.Value = 'SURF';
            app.SearchButton_3 = uibutton(app.InputPanel, 'push');
            app.SearchButton_3.ButtonPushedFcn = createCallbackFcn(app, @SearchButton_3Pushed, true);
            app.SearchButton_3.Text = 'Search';
            app.UseBoFEditFieldLabel = uilabel(app.InputPanel);
            app.UseBoFEditFieldLabel.Text = 'Use BoF';
            app.UseBoFEditField = uieditfield(app.InputPanel, 'text');
            app.UseBoFEditField.Value = 'c:\';
            app.ClassifierPreviewPanel = uipanel(app.UIFigure);
            app.ClassifierPreviewPanel.Title = 'Classifier Preview';
            app.UIAxes = uiaxes(app.ClassifierPreviewPanel);
            title(app.UIAxes, 'Preview')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cow_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end