classdef d_brisk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConfirmButton                  matlab.ui.control.Button
        MinimumQualityEditFieldLabel   matlab.ui.control.Label
        MinimumQualityEditField        matlab.ui.control.NumericEditField
        MinimumContrastEditFieldLabel  matlab.ui.control.Label
        MinimumContrastEditField       matlab.ui.control.NumericEditField
        NumberofOctavesEditFieldLabel  matlab.ui.control.Label
        NumberofOctavesEditField       matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainApp = mainApp;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % save values
            app.MainApp.storage.d_brisk_minQuality = app.MinimumQualityEditField.Value; 
            app.MainApp.storage.d_brisk_minContrast = app.MinimumContrastEditField.Value; 
            app.MainApp.storage.d_brisk_numOctaves = app.NumberofOctavesEditField.Value; 
            
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.MinimumQualityEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumQualityEditFieldLabel.Text = 'Minimum Quality';
            app.MinimumQualityEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumQualityEditField.Limits = [0 1];
            app.MinimumQualityEditField.Value = 0.1;
            app.MinimumContrastEditFieldLabel = uilabel(app.UIFigure);
            app.MinimumContrastEditFieldLabel.Text = 'Minimum Contrast';
            app.MinimumContrastEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinimumContrastEditField.Limits = [0 1];
            app.MinimumContrastEditField.Value = 0.2;
            app.NumberofOctavesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofOctavesEditFieldLabel.Text = 'Number of Octaves';
            app.NumberofOctavesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofOctavesEditField.Limits = [1 4];
            app.NumberofOctavesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofOctavesEditField.Value = 4;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = d_brisk(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end