classdef d_kaze < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ThresholdEditFieldLabel        matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.NumericEditField
        NumberofOctavesEditFieldLabel  matlab.ui.control.Label
        NumberofOctavesEditField       matlab.ui.control.NumericEditField
        NumberofScaleLevelsEditFieldLabel  matlab.ui.control.Label
        NumberofScaleLevelsEditField   matlab.ui.control.NumericEditField
        ConfirmButton                  matlab.ui.control.Button
        DiffusionDropDownLabel         matlab.ui.control.Label
        DiffusionDropDown              matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainApp = mainApp;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % save values
            app.MainApp.storage.d_kaze_diffusion = app.DiffusionDropDown.Value;
            app.MainApp.storage.d_kaze_threshold = app.ThresholdEditField.Value;
            app.MainApp.storage.d_kaze_numOctaves = app.NumberofOctavesEditField.Value;
            app.MainApp.storage.d_kaze_numScaleLevels = app.NumberofScaleLevelsEditField.Value;
            
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.Value = 0.0001;
            app.NumberofOctavesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofOctavesEditFieldLabel.Text = 'Number of Octaves';
            app.NumberofOctavesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofOctavesEditField.Limits = [1 4];
            app.NumberofOctavesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofOctavesEditField.Value = 3;
            app.NumberofScaleLevelsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofScaleLevelsEditFieldLabel.Text = 'Number of Scale Levels';
            app.NumberofScaleLevelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofScaleLevelsEditField.Limits = [1 4];
            app.NumberofScaleLevelsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofScaleLevelsEditField.Value = 3;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.DiffusionDropDownLabel = uilabel(app.UIFigure);
            app.DiffusionDropDownLabel.Text = 'Diffusion';
            app.DiffusionDropDown = uidropdown(app.UIFigure);
            app.DiffusionDropDown.Items = {'region', 'sharpedge', 'edge'};
            app.DiffusionDropDown.Value = 'region';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = d_kaze(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end