classdef d_mser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ThresholdDeltaEditFieldLabel    matlab.ui.control.Label
        ThresholdDeltaEditField         matlab.ui.control.NumericEditField
        MinRegionAreaRangeEditFieldLabel  matlab.ui.control.Label
        MinRegionAreaRangeEditField     matlab.ui.control.NumericEditField
        MaxAreaVariationEditFieldLabel  matlab.ui.control.Label
        MaxAreaVariationEditField       matlab.ui.control.NumericEditField
        ConfirmButton                   matlab.ui.control.Button
        MaxRegionAreaRangeEditFieldLabel  matlab.ui.control.Label
        MaxRegionAreaRangeEditField     matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainApp = mainApp;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % save values
            app.MainApp.storage.d_mser_thresholdDelta = app.ThresholdDeltaEditField.Value;
            app.MainApp.storage.d_mser_regionAreaRange = [app.MinRegionAreaRangeEditField.Value app.MaxRegionAreaRangeEditField.Value];
            app.MainApp.storage.d_mser_maxAreaVariation = app.MaxAreaVariationEditField.Value;
            
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ThresholdDeltaEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdDeltaEditFieldLabel.Text = 'Threshold Delta';
            app.ThresholdDeltaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdDeltaEditField.Limits = [0.8 4];
            app.ThresholdDeltaEditField.Value = 2;
            app.MinRegionAreaRangeEditFieldLabel = uilabel(app.UIFigure);
            app.MinRegionAreaRangeEditFieldLabel.Text = 'Min Region Area Range';
            app.MinRegionAreaRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MinRegionAreaRangeEditField.Limits = [0 Inf];
            app.MinRegionAreaRangeEditField.ValueDisplayFormat = '%.0f';
            app.MinRegionAreaRangeEditField.Value = 30;
            app.MaxAreaVariationEditFieldLabel = uilabel(app.UIFigure);
            app.MaxAreaVariationEditFieldLabel.Text = 'Max Area Variation';
            app.MaxAreaVariationEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxAreaVariationEditField.Limits = [0.1 1];
            app.MaxAreaVariationEditField.Value = 0.25;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            app.MaxRegionAreaRangeEditFieldLabel = uilabel(app.UIFigure);
            app.MaxRegionAreaRangeEditFieldLabel.Text = 'Max Region Area Range';
            app.MaxRegionAreaRangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaxRegionAreaRangeEditField.Limits = [0 Inf];
            app.MaxRegionAreaRangeEditField.ValueDisplayFormat = '%.0f';
            app.MaxRegionAreaRangeEditField.Value = 14000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = d_mser(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end