classdef d_orb < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ScaleFactorEditFieldLabel      matlab.ui.control.Label
        ScaleFactorEditField           matlab.ui.control.NumericEditField
        NumberofDecompLevelsEditFieldLabel  matlab.ui.control.Label
        NumberofDecompLevelsEditField  matlab.ui.control.NumericEditField
        ConfirmButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainApp = mainApp;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % save values
            app.MainApp.storage.d_orb_scaleFactor = app.ScaleFactorEditField.Value;
            app.MainApp.storage.d_orb_numDecompLevels = app.NumberofDecompLevelsEditField.Value;
            
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScaleFactorEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.ScaleFactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.ScaleFactorEditField.Limits = [1 Inf];
            app.ScaleFactorEditField.Value = 1.2;
            app.NumberofDecompLevelsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofDecompLevelsEditFieldLabel.Text = 'Number of Decomp. Levels';
            app.NumberofDecompLevelsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDecompLevelsEditField.Limits = [1 Inf];
            app.NumberofDecompLevelsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofDecompLevelsEditField.Value = 8;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = d_orb(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end