classdef e_hog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CellSizeEditFieldLabel      matlab.ui.control.Label
        CellSizeEditField           matlab.ui.control.NumericEditField
        BlockSizeEditFieldLabel     matlab.ui.control.Label
        BlockSizeEditField          matlab.ui.control.NumericEditField
        NumberofBinsEditFieldLabel  matlab.ui.control.Label
        NumberofBinsEditField       matlab.ui.control.NumericEditField
        ConfirmButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.MainApp = mainApp;
        end

        % Button pushed function: ConfirmButton
        function ConfirmButtonPushed(app, event)
            % save values
            app.MainApp.storage.e_hog_cellSize = [app.CellSizeEditField.Value app.CellSizeEditField.Value];
            app.MainApp.storage.e_hog_blockSize = [app.BlockSizeEditField.Value app.BlockSizeEditField.Value];
            app.MainApp.storage.e_hog_numBins = app.NumberofBinsEditField.Value;
            
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CellSizeEditFieldLabel = uilabel(app.UIFigure);
            app.CellSizeEditFieldLabel.Text = 'Cell Size';
            app.CellSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.CellSizeEditField.Limits = [2 16];
            app.CellSizeEditField.ValueDisplayFormat = '%.0f';
            app.CellSizeEditField.Value = 8;
            app.BlockSizeEditFieldLabel = uilabel(app.UIFigure);
            app.BlockSizeEditFieldLabel.Text = 'Block Size';
            app.BlockSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.BlockSizeEditField.Limits = [2 8];
            app.BlockSizeEditField.ValueDisplayFormat = '%.0f';
            app.BlockSizeEditField.Value = 2;
            app.NumberofBinsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofBinsEditFieldLabel.Text = 'Number of Bins';
            app.NumberofBinsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofBinsEditField.Limits = [1 Inf];
            app.NumberofBinsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofBinsEditField.Value = 9;
            app.ConfirmButton = uibutton(app.UIFigure, 'push');
            app.ConfirmButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmButtonPushed, true);
            app.ConfirmButton.Text = 'Confirm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = e_hog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end